classdef climatewedgesV003 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        LoadFileMenu                  matlab.ui.container.Menu
        LoadWedgesFileMenu            matlab.ui.container.Menu
        LoadConstraintsFileMenu       matlab.ui.container.Menu
        LoadStrategyFileMenu          matlab.ui.container.Menu
        Menu2                         matlab.ui.container.Menu
        TabGroup                      matlab.ui.container.TabGroup
        StrategiesTab                 matlab.ui.container.Tab
        Label                         matlab.ui.control.Label
        ElectricityStrategiesListBoxLabel  matlab.ui.control.Label
        ElectricityStrategiesListBox  matlab.ui.control.ListBox
        FuelStrategiesListBoxLabel    matlab.ui.control.Label
        FuelStrategiesListBox         matlab.ui.control.ListBox
        TransportationStrategiesListBoxLabel  matlab.ui.control.Label
        TransportationStrategiesListBox  matlab.ui.control.ListBox
        BiostorageStrategiesListBoxLabel  matlab.ui.control.Label
        BiostorageStrategiesListBox   matlab.ui.control.ListBox
        Tab2                          matlab.ui.container.Tab
        UIAxes                        matlab.ui.control.UIAxes
        StartYearTextAreaLabel        matlab.ui.control.Label
        StartYearTextArea             matlab.ui.control.TextArea
        EndYearTextAreaLabel          matlab.ui.control.Label
        EndYearTextArea               matlab.ui.control.TextArea
        EnterastartandendLabel        matlab.ui.control.Label
        yeartoeditthegraphsLabel      matlab.ui.control.Label
        domainLabel                   matlab.ui.control.Label
    end


    properties (Access = private)
        WedgesData      % This property will hold processed data.
        ConstraintsData % This property will hold processed data.
        StrategyData    % This property will hold processed data.
        XDataHist  %Stores historical XData
        YDataHist  %Stores historical YData
    end

    methods (Access = private)
    
    function Data = readFile(app, fileName)
    % This fucntion's purpose is to read in and process
    % the any of the input files and outputs the data as a struct.
    % You must access data in the file using parenthesis at the begining of
    % the call. i.e. Data(n).field the nth element in that field.
    try
        Data = readtable(fileName);
        Data = table2struct(Data,'ToScalar',true);
        
        % Data Checking
        % Data Checking is done by using the field names.
        % Assuming the header is provided the same in every file, 
        % the field names will be consistent.


        try
            identifier = fieldnames(Data);
            identifier = identifier{2};

            % Check for the wedges file
            if strcmp(identifier, 'Category')
                Data.Flag = 'wedges';

            % Check for the constraints file
            elseif strcmp(identifier, 'YearStart')
                Data.Flag = 'strategy';
            % Check for the strategy File
            elseif strcmp(identifier, 'Constraints')
                Data.Flag = 'constraints';

            % If it doesn't fit any of the above formats, it is not a useable file.
            else
                error
            end
        catch
            error
        end
    catch
%        disp('The selected file does not comply with any of the required formats.');
        Data = -1;
    end
    end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            info = tdfread('historicaldata.txt');
            app.XDataHist = info.Year;
            app.YDataHist = info.fossil_fuel_and_industry_GtC0x2Fyr;
            plot(app.UIAxes, app.XDataHist, app.YDataHist);
            app.UIAxes.XLim = [app.XDataHist(1), app.XDataHist(end)];
        end

        % Menu selected function: LoadWedgesFileMenu
        function LoadWedgesFileMenuSelected(app, event)
            code = readFile(app, uigetfile('.txt'));
            
            if ~isequal(code, -1)
                climatewedge.WedgesData = code;
            else
                disp('Please select a valid file...');
            end
        end

        % Value changed function: ElectricityStrategiesListBox
        function ElectricityStrategiesListBoxValueChanged(app, event)
            
            value = app.ElectricityStrategiesListBox.Value;
            %the line below is to assign each strategy as an item in the list
            %app.ElectricityStrategiesListBox.Items=Strategies(:);
            %limit decided by constraints (can select 4 out of 6 for example)
            %if no limit is given in constraint file, then you can select all strategies
        end

        % Value changed function: TransportationStrategiesListBox
        function TransportationStrategiesListBoxValueChanged(app, event)
            
            value = app.TransportationStrategiesListBox.Value;
            %the line below is to assign each strategy as an item in the list
            %app.TransportationStrategySelection.Items=Strategies(:);
            %limit decided by constraints (can select 4 out of 6 for example)
            %if no limit is given in constraint file, then you can select all strategies
        end

        % Value changed function: FuelStrategiesListBox
        function FuelStrategiesListBoxValueChanged(app, event)
            
            value = app.FuelStrategiesListBox.Value;
            %the line below is to assign each strategy as an item in the list
            %app.FuelStrategySelection.Items=Strategies(:);
            %limit decided by constraints (can select 4 out of 6 for example)
            %if no limit is given in constraint file, then you can select all strategies
        end

        % Value changed function: BiostorageStrategiesListBox
        function BiostorageStrategiesListBoxValueChanged(app, event)
           
            value = app.BiostorageStrategiesListBox.Value;
            %the line below is to assign each strategy as an item in the list
            %app.BiostorageStrategySelection.Items=Strategies(:);
            %limit decided by constraints (can select 4 out of 6 for example)
            %if no limit is given in constraint file, then you can select all strategies
        end

        % Value changed function: StartYearTextArea
        function StartYearTextAreaValueChanged(app, event)
             %~~~~~~~~~~~~
            % STILL UNDER CONSTRUCTION
            %~~~~~~~~~~~~
            
            value = app.StartYearTextArea.Value;
            
            if value > 99
                app.UIAxes.XLim = [app.XData(1), app.XData(end)];
            elseif value < 2
                app.UIAxes.XLim = [app.XData(1), app.XData(2)];
            else
                app.UIAxes.XLim = [app.XData(1), app.XData(floor(end*(value/100)))];
            end
            
        end

        % Value changed function: EndYearTextArea
        function EndYearTextAreaValueChanged(app, event)
              %~~~~~~~~~~~~
            % STILL UNDER CONSTRUCTION
            %~~~~~~~~~~~~
            
            value = app.EndYearTextArea.Value;
            if value > 99
                app.UIAxes.XLim = [app.XData(1), app.XData(end)];
            elseif value < 2
                app.UIAxes.XLim = [app.XData(1), app.XData(2)];
            else
                app.UIAxes.XLim = [app.XData(1), app.XData(floor(end*(value/100)))];
            end
            
        end

        % Menu selected function: LoadConstraintsFileMenu
        function LoadConstraintsFileMenuSelected(app, event)
            
            code = readFile(app, uigetfile('.txt'));
            
            if ~isequal(code, -1)
                climatewedge.ConstraintsData = code;
            else
                disp('Please select a valid file...');
            end
            
        end

        % Menu selected function: LoadStrategyFileMenu
        function LoadStrategyFileMenuSelected(app, event)
            
            code = readFile(app, uigetfile('.txt'));
            
            if ~isequal(code, -1)
                climatewedge.StrategyData = code;
            else
                disp('Please select a valid file...');
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.LoadFileMenu = uimenu(app.UIFigure);
            app.LoadFileMenu.Text = 'Load File';
            app.LoadWedgesFileMenu = uimenu(app.LoadFileMenu);
            app.LoadWedgesFileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadWedgesFileMenuSelected, true);
            app.LoadWedgesFileMenu.Text = 'Load Wedges File';
            app.LoadConstraintsFileMenu = uimenu(app.LoadFileMenu);
            app.LoadConstraintsFileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadConstraintsFileMenuSelected, true);
            app.LoadConstraintsFileMenu.Text = 'Load Constraints File';
            app.LoadStrategyFileMenu = uimenu(app.LoadFileMenu);
            app.LoadStrategyFileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadStrategyFileMenuSelected, true);
            app.LoadStrategyFileMenu.Text = 'Load Strategy File';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.StrategiesTab = uitab(app.TabGroup);
            app.StrategiesTab.Title = 'Strategies';
            app.Label = uilabel(app.StrategiesTab);
            app.Label.Text = 'Some sectors may have constraints on how many strategies can be selected at a time.';
            app.ElectricityStrategiesListBoxLabel = uilabel(app.StrategiesTab);
            app.ElectricityStrategiesListBoxLabel.Text = 'Electricity Strategies';
            app.ElectricityStrategiesListBox = uilistbox(app.StrategiesTab);
            app.ElectricityStrategiesListBox.Items = {'Strategy 1', 'Strategy 2', 'Strategy 3', 'Strategy 4'};
            app.ElectricityStrategiesListBox.Multiselect = 'on';
            app.ElectricityStrategiesListBox.ValueChangedFcn = createCallbackFcn(app, @ElectricityStrategiesListBoxValueChanged, true);
            app.ElectricityStrategiesListBox.Value = {'Strategy 1'};
            app.FuelStrategiesListBoxLabel = uilabel(app.StrategiesTab);
            app.FuelStrategiesListBoxLabel.Text = 'Fuel Strategies';
            app.FuelStrategiesListBox = uilistbox(app.StrategiesTab);
            app.FuelStrategiesListBox.Items = {'Strategy 1', 'Strategy 2', 'Strategy 3', 'Strategy 4'};
            app.FuelStrategiesListBox.Multiselect = 'on';
            app.FuelStrategiesListBox.ValueChangedFcn = createCallbackFcn(app, @FuelStrategiesListBoxValueChanged, true);
            app.FuelStrategiesListBox.Value = {'Strategy 1'};
            app.TransportationStrategiesListBoxLabel = uilabel(app.StrategiesTab);
            app.TransportationStrategiesListBoxLabel.Text = 'Transportation Strategies';
            app.TransportationStrategiesListBox = uilistbox(app.StrategiesTab);
            app.TransportationStrategiesListBox.Items = {'Strategy 1', 'Strategy 2', 'Strategy 3', 'Strategy 4'};
            app.TransportationStrategiesListBox.Multiselect = 'on';
            app.TransportationStrategiesListBox.ValueChangedFcn = createCallbackFcn(app, @TransportationStrategiesListBoxValueChanged, true);
            app.TransportationStrategiesListBox.Value = {'Strategy 1'};
            app.BiostorageStrategiesListBoxLabel = uilabel(app.StrategiesTab);
            app.BiostorageStrategiesListBoxLabel.Text = 'Biostorage Strategies';
            app.BiostorageStrategiesListBox = uilistbox(app.StrategiesTab);
            app.BiostorageStrategiesListBox.Items = {'Strategy 1', 'Strategy 2', 'Strategy 3', 'Strategy 4'};
            app.BiostorageStrategiesListBox.Multiselect = 'on';
            app.BiostorageStrategiesListBox.ValueChangedFcn = createCallbackFcn(app, @BiostorageStrategiesListBoxValueChanged, true);
            app.BiostorageStrategiesListBox.Value = {'Strategy 1'};
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.CLim = [0 1];
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.ZTick = [0 0.5 1];
            app.StartYearTextAreaLabel = uilabel(app.UIFigure);
            app.StartYearTextAreaLabel.Text = 'Start Year';
            app.StartYearTextArea = uitextarea(app.UIFigure);
            app.StartYearTextArea.ValueChangedFcn = createCallbackFcn(app, @StartYearTextAreaValueChanged, true);
            app.EndYearTextAreaLabel = uilabel(app.UIFigure);
            app.EndYearTextAreaLabel.Text = 'End Year';
            app.EndYearTextArea = uitextarea(app.UIFigure);
            app.EndYearTextArea.ValueChangedFcn = createCallbackFcn(app, @EndYearTextAreaValueChanged, true);
            app.EnterastartandendLabel = uilabel(app.UIFigure);
            app.EnterastartandendLabel.Text = 'Enter a start and end';
            app.yeartoeditthegraphsLabel = uilabel(app.UIFigure);
            app.yeartoeditthegraphsLabel.Text = 'year to edit the graph''s';
            app.domainLabel = uilabel(app.UIFigure);
            app.domainLabel.Text = 'domain.';
        end
    end

    methods (Access = public)

        % Construct app
        function app = climatewedgesV003

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end